classdef FlightLogAnalysisApplication < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        ControlPanel          matlab.ui.container.Panel
        GridLayout3           matlab.ui.container.GridLayout
        ShowHidePannerButton  matlab.ui.control.Button
        SaveFigurepngButton   matlab.ui.control.Button
        SaveFigurefigButton   matlab.ui.control.Button
        NewFigureButton       matlab.ui.control.Button
        NewAxesScatterButton  matlab.ui.control.Button
        NewAxesLineButton     matlab.ui.control.Button
        EditAxesButton        matlab.ui.control.Button
        DeleteSelectedButton  matlab.ui.control.Button
        EntityTreePanel       matlab.ui.container.Panel
        Tree                  matlab.ui.container.Tree
        DataPanel             matlab.ui.container.Panel
        GridLayout2           matlab.ui.container.GridLayout
        Label                 matlab.ui.control.Label
        LoadDataButton        matlab.ui.control.Button
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = public)
        DataManager
        
        PlotManager
        SelectedNode
        CurrentData
        Figures = []
        NextFigureId = 1
        NextAxesId = 1
        PannerVisible logical = true
        TabGroup

    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            [file, path] = uigetfile('*.csv', 'Select Flight Log Data');
            if isequal(file, 0)
                return;
            end
            
            filename = fullfile(path, file);
            
            try
                % Charger DataManager depuis src/
                addpath(fullfile(fileparts(mfilename('fullpath')), 'src'));
                dataManager = FlightDataManager(); 
                
                success = dataManager.loadData(filename);
                if success
                    app.Label.Text = sprintf('Loaded: %s', file);
                    dataManager.calculateAll();
                    app.CurrentData = dataManager;
                    app.DataManager = dataManager;
                    
                    app.Label.Text = [app.Label.Text, ' - Prêt à créer des figures'];
                else
                    app.Label.Text = 'Error loading file';
                end
            catch ME
                app.Label.Text = sprintf('Error: %s', ME.message);
            end
        end

        % Button pushed function: DeleteSelectedButton
        function DeleteSelectedButtonPushed(app, event)
            [figId, axesId] = SupportFunctions.getSelectedIds(app);
            
            if isempty(figId)
                uialert(app.UIFigure, 'Please select a Figure or Axes in the tree first.', 'No Selection');
                return;
            end
            
            if ~isempty(axesId)
                SupportFunctions.deleteAxes(app, figId, axesId);
            else
                SupportFunctions.deleteFigure(app, figId);
            end
        end

        % Button pushed function: EditAxesButton
        function EditAxesButtonPushed(app, event)
            [figId, axesId] = SupportFunctions.getSelectedIds(app);
    
            fprintf('=== EDIT AXES START ===\n');
            fprintf('figId: %d, axesId: %d\n', figId, axesId);
            
            if isempty(axesId)
                uialert(app.UIFigure, 'Please select an AXES in the tree first.', 'No Axes Selected');
                return;
            end
            
            if isempty(app.CurrentData)
                uialert(app.UIFigure, 'Load data first to configure axes.', 'No Data');
                return;
            end
            
            % Configuration des variables
            availableVars = app.CurrentData.RawData.Properties.VariableNames;
            if ~isempty(app.CurrentData.CalculatedData)
                calcVars = fieldnames(app.CurrentData.CalculatedData);
                availableVars = [availableVars, calcVars'];
            end
            
            fprintf('Available variables: %s\n', strjoin(availableVars, ', '));
            
            [indx, tf] = listdlg('ListString', availableVars, ...
                'SelectionMode', 'multiple', ...
                'Name', 'Select Variables to Plot', ...
                'ListSize', [300, 200]);
            
            if tf
                selectedVars = availableVars(indx);
                fprintf('User selected: %s\n', strjoin(selectedVars, ', '));
                
                % Trouver l'index de la figure
                figureIndex = SupportFunctions.findFigureIndex(app, figId);
                fprintf('Found figure at index: %d\n', figureIndex);
                
                if isempty(figureIndex)
                    uialert(app.UIFigure, 'Figure not found.', 'Error');
                    return;
                end
                
                % Vérifier la structure de l'axe
                axFieldName = sprintf('axes%d', axesId);
                fprintf('Looking for axes field: %s\n', axFieldName);
                
                if isfield(app.Figures(figureIndex).Axes, axFieldName)
                    fprintf('Axes field found. Current Variables: %s\n', ...
                        strjoin(app.Figures(figureIndex).Axes.(axFieldName).Variables, ', '));
                    
                    % SAUVEGARDER les variables
                    app.Figures(figureIndex).Axes.(axFieldName).Variables = selectedVars;
                    
                    fprintf('Variables saved: %s\n', ...
                        strjoin(app.Figures(figureIndex).Axes.(axFieldName).Variables, ', '));
                    
                    % VÉRIFICATION IMMÉDIATE
                    fprintf('VERIFICATION - Reading back variables: %s\n', ...
                        strjoin(app.Figures(figureIndex).Axes.(axFieldName).Variables, ', '));
                    
                    % Mettre à jour le plot
                    SupportFunctions.updateAxesPlot(app, figureIndex, axesId);
                    app.Label.Text = sprintf('Axes %d configured with %d variables', axesId, length(selectedVars));
                    
                else
                    fprintf('ERROR: Axes field %s not found in figure %d\n', axFieldName, figureIndex);
                    fprintf('Available axes fields: %s\n', ...
                        strjoin(fieldnames(app.Figures(figureIndex).Axes), ', '));
                    uialert(app.UIFigure, 'Axes not found.', 'Error');
                end
            else
                fprintf('User cancelled variable selection\n');
            end
        end

        % Button pushed function: NewAxesLineButton
        function NewAxesLineButtonPushed(app, event)
            [figId, ~] = SupportFunctions.getSelectedIds(app);
            
            if isempty(figId)
                uialert(app.UIFigure, 'Please select a FIGURE in the tree first.', 'No Figure Selected');
                return;
            end
            
            SupportFunctions.addNewAxes(app, figId, 'line');
            app.Label.Text = sprintf('Nouvel axe Line ajouté à Figure %d', figId);
        end

        % Button pushed function: NewAxesScatterButton
        function NewAxesScatterButtonPushed(app, event)
            [figId, ~] = SupportFunctions.getSelectedIds(app);
            
            if isempty(figId)
                uialert(app.UIFigure, 'Please select a FIGURE in the tree first.', 'No Figure Selected');
                return;
            end
            
            SupportFunctions.addNewAxes(app, figId, 'scatter');
            app.Label.Text = sprintf('Nouvel axe Scatter ajouté à Figure %d', figId);
        end

        % Button pushed function: NewFigureButton
        function NewFigureButtonPushed(app, event)
            try
                SupportFunctions.createNewFigure(app);
            catch ME
                app.Label.Text = sprintf('Error creating figure: %s', ME.message);
            end
        end

        % Button pushed function: SaveFigurefigButton
        function SaveFigurefigButtonPushed(app, event)
            if isempty(app.SelectedNode) || ~startsWith(app.SelectedNode.Text, 'Figure')
                uialert(app.UIFigure, 'Please select a figure to save.', 'No Figure Selected');
                return;
            end
            
            figId = str2double(extractAfter(app.SelectedNode.Text, 'Figure '));
            app.saveFigureAsFIG(figId);
        end

        % Button pushed function: SaveFigurepngButton
        function SaveFigurepngButtonPushed(app, event)
            [figId, ~] = SupportFunctions.getSelectedIds(app);
            
            if isempty(figId)
                uialert(app.UIFigure, 'Please select a FIGURE in the tree first.', 'No Figure Selected');
                return;
            end
            
            SupportFunctions.saveFigureAsPNG(app, figId);
        end

        % Button pushed function: ShowHidePannerButton
        function ShowHidePannerButtonPushed(app, event)
            try
                app.PannerVisible = ~app.PannerVisible;
                
                % Mettre à jour TOUTES les figures
                figFields = fieldnames(app.Figures);
                for i = 1:length(figFields)
                    figName = figFields{i};
                    if isfield(app.Figures.(figName), 'Panner')
                        app.Figures.(figName).Panner.Visible = app.PannerVisible;
                    end
                end
                
                % Mettre à jour le texte du bouton
                if app.PannerVisible
                    app.ShowHidePannerButton.Text = 'Hide Panner';
                    app.Label.Text = 'Panner: VISIBLE';
                else
                    app.ShowHidePannerButton.Text = 'Show Panner';
                    app.Label.Text = 'Panner: HIDDEN';
                end
                
                disp(['Panner visibility: ', num2str(app.PannerVisible)]);
                
            catch ME
                disp('Error in ShowHidePanner:');
                disp(ME.message);
            end
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            if ~isempty(event.SelectedNodes)
                app.SelectedNode = event.SelectedNodes(1);
                nodeText = app.SelectedNode.Text;
                
                % Mettre à jour le label avec la sélection
                if startsWith(nodeText, 'Figure')
                    app.Label.Text = sprintf('Selected: %s - Use "New Axes" buttons', nodeText);
                elseif startsWith(nodeText, 'Axes')
                    parentText = app.SelectedNode.Parent.Text;
                    app.Label.Text = sprintf('Selected: %s in %s - Use "Edit Axes"', nodeText, parentText);
                else
                    app.Label.Text = sprintf('Selected: %s', nodeText);
                end
            else
                app.SelectedNode = [];
                app.Label.Text = 'No selection - Select a Figure or Axes';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.DataPanel = uipanel(app.GridLayout);
            app.DataPanel.Title = 'Data';
            app.GridLayout2 = uigridlayout(app.DataPanel);
            app.LoadDataButton = uibutton(app.GridLayout2, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.Label = uilabel(app.GridLayout2);
            app.EntityTreePanel = uipanel(app.GridLayout);
            app.EntityTreePanel.Title = 'Entity Tree';
            app.Tree = uitree(app.EntityTreePanel);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.ControlPanel = uipanel(app.GridLayout);
            app.ControlPanel.Title = 'Control Panel';
            app.GridLayout3 = uigridlayout(app.ControlPanel);
            app.DeleteSelectedButton = uibutton(app.GridLayout3, 'push');
            app.DeleteSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteSelectedButtonPushed, true);
            app.DeleteSelectedButton.Text = 'Delete Selected';
            app.EditAxesButton = uibutton(app.GridLayout3, 'push');
            app.EditAxesButton.ButtonPushedFcn = createCallbackFcn(app, @EditAxesButtonPushed, true);
            app.EditAxesButton.Text = 'Edit Axes';
            app.NewAxesLineButton = uibutton(app.GridLayout3, 'push');
            app.NewAxesLineButton.ButtonPushedFcn = createCallbackFcn(app, @NewAxesLineButtonPushed, true);
            app.NewAxesLineButton.Text = 'New Axes (Line)';
            app.NewAxesScatterButton = uibutton(app.GridLayout3, 'push');
            app.NewAxesScatterButton.ButtonPushedFcn = createCallbackFcn(app, @NewAxesScatterButtonPushed, true);
            app.NewAxesScatterButton.Text = 'New Axes (Scatter)';
            app.NewFigureButton = uibutton(app.GridLayout3, 'push');
            app.NewFigureButton.ButtonPushedFcn = createCallbackFcn(app, @NewFigureButtonPushed, true);
            app.NewFigureButton.Text = 'New Figure';
            app.SaveFigurefigButton = uibutton(app.GridLayout3, 'push');
            app.SaveFigurefigButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFigurefigButtonPushed, true);
            app.SaveFigurefigButton.Text = 'Save Figure (.fig)';
            app.SaveFigurepngButton = uibutton(app.GridLayout3, 'push');
            app.SaveFigurepngButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFigurepngButtonPushed, true);
            app.SaveFigurepngButton.Text = 'Save Figure (.png)';
            app.ShowHidePannerButton = uibutton(app.GridLayout3, 'push');
            app.ShowHidePannerButton.ButtonPushedFcn = createCallbackFcn(app, @ShowHidePannerButtonPushed, true);
            app.ShowHidePannerButton.Text = 'Show / Hide Panner';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FlightLogAnalysisApplication

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end